classdef Plotable < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        EnterMedianWristOnsetPointEditField_2Label  matlab.ui.control.Label
        EnterMedianWristOnsetPointEditField_2  matlab.ui.control.NumericEditField
        EnterUlnarWristOnsetPointEditFieldLabel  matlab.ui.control.Label
        EnterUlnarWristOnsetPointEditField  matlab.ui.control.NumericEditField
        SelecttheMedianElbowaxoscopefileButton  matlab.ui.control.Button
        SelecttheMedianWristaxoscopefileButton  matlab.ui.control.Button
        SelecttheUlnarWristaxoscopefileButton  matlab.ui.control.Button
        PlotRawWaveformsButton      matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        Lamp                        matlab.ui.control.Lamp
        Label_2                     matlab.ui.control.Label
        Lamp_2                      matlab.ui.control.Lamp
        Label_3                     matlab.ui.control.Label
        Lamp_3                      matlab.ui.control.Lamp
        Label_4                     matlab.ui.control.Label
        Lamp_4                      matlab.ui.control.Lamp
        Label_5                     matlab.ui.control.Label
        Lamp_5                      matlab.ui.control.Lamp
        Label_6                     matlab.ui.control.Label
        Lamp_6                      matlab.ui.control.Lamp
        PlotShiftedWaveformsButton  matlab.ui.control.Button
        AnalyzeWaveformsButton      matlab.ui.control.Button
        EnterMedianElbowOnsetPointEditFieldLabel  matlab.ui.control.Label
        EnterMedianElbowOnsetPointEditField  matlab.ui.control.NumericEditField
        ProgramtoInterrogateNerveCrossoverLabel  matlab.ui.control.Label
        Label_7                     matlab.ui.control.Label
        Label_8                     matlab.ui.control.Label
        ResetButton                 matlab.ui.control.Button
        SelecttheUlnarElbowaxoscopefileButton  matlab.ui.control.Button
        Lamp_7Label                 matlab.ui.control.Label
        Lamp_7                      matlab.ui.control.Lamp
        EnterUlnarElbowOnsetPointEditFieldLabel  matlab.ui.control.Label
        EnterUlnarElbowOnsetPointEditField  matlab.ui.control.NumericEditField
        Lamp_8Label                 matlab.ui.control.Label
        Lamp_8                      matlab.ui.control.Lamp
        Image                       matlab.ui.control.Image
        Image_2                     matlab.ui.control.Image
        Image_3                     matlab.ui.control.Image
        Image_4                     matlab.ui.control.Image
        RightPanel                  matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: 
        % SelecttheMedianElbowaxoscopefileButton
        function SelecttheMedianElbowaxoscopefileButtonPushed(app, event)
            global me
            me = axoload / 1000;
            prompt = {'Were you able to select the appropriate file? Enter [y/n]'};
            dlgtitle = 'Confirmation of file.';
            definput={'y / n'};
            dims = [1 40];
            opts.Interpreter ='tex';
            selected = inputdlg(prompt, dlgtitle, dims, definput, opts); 
            
            if selected == "y"
                app.Lamp.Color = 'green';
            else
                app.Lamp.Color = 'red';
            end
            
            while selected == "n"
                app.Lamp.Color = 'red';
                axoload;
                selected = inputdlg(prompt, dlgtitle, dims, definput, opts); 
                if selected == "y"
                    me = ans / 1000;
                    app.Lamp.Color = 'green';
                    continue 
                end
            end
        end

        % Callback function
        function AppropriatefileEnterynEditFieldValueChanging(app, event)
            
        end

        % Button pushed function: 
        % SelecttheMedianWristaxoscopefileButton
        function SelecttheMedianWristaxoscopefileButtonPushed(app, event)
            global mw
            mw = axoload / 1000;
            prompt = {'Were you able to select the appropriate file? Enter [y/n]'};
            dlgtitle = 'Confirmation of file.';
            definput={'y / n'};
            dims = [1 40];
            opts.Interpreter ='tex';
            selected = inputdlg(prompt, dlgtitle, dims, definput, opts);
            app.Lamp_2.Color = 'green';
            
            if selected == "y"
                app.Lamp_2.Color = 'green';
            else
                app.Lamp_2.Color = 'red';
            end
            
            while selected == "n"
                app.Lamp_2.Color = 'red';
                axoload;
                selected = inputdlg(prompt, dlgtitle, dims, definput, opts);
                if selected == "y"
                    mw = ans / 1000;
                    app.Lamp_2.Color = 'green';
                    continue 
                end
            end
        end

        % Button pushed function: 
        % SelecttheUlnarWristaxoscopefileButton
        function SelecttheUlnarWristaxoscopefileButtonPushed(app, event)
            global uw
            prompt = {'Were you able to select the appropriate file? Enter [y/n]'};
            dlgtitle = 'Confirmation of file.';
            definput={'y / n'};
            dims = [1 40];
            opts.Interpreter ='tex';
            uw = axoload / 1000;
            selected = inputdlg(prompt, dlgtitle, dims, definput, opts);
            app.Lamp_3.Color = 'green';
            
            if selected == "y"
                app.Lamp_3.Color = 'green';
            else
                app.Lamp_3.Color = 'red';
            end
            
            while selected == "n"
                app.Lamp_3.Color = 'red';
                axoload;
                selected = inputdlg(prompt, dlgtitle, dims, definput, opts); 
                if selected == "y"
                    uw = ans / 1000;
                    app.Lamp_3.Color = 'green';
                    continue 
                end
            end
        end

        % Button pushed function: PlotRawWaveformsButton
        function PlotRawWaveformsButtonPushed(app, event)
            global me
            global mw
            global uw
            global ue
            hold off
            
            figure('Name',"Plot of Raw Waveforms.",'Color','#fffeea');
            plot(me,'color','[0.1451 0.5882 0.7451]','LineWidth',3)
            hold on
            plot(mw,'color','[0.9059 0.1647 0.1542]','LineWidth',3)
            plot(uw,'color','[0.8863 0.5294 0.2627]','LineWidth',3)
            plot(ue,'color','[0.6431 0.5176 0.8157]','LineWidth',3)
            
            % legends & axes
            lgd = legend('Median Elbow','Median Wrist','Ulnar Wrist','Ulnar Elbow');
            lgd.FontSize = 25;
            
            lgd = title('Plot of Shifted Median Elbow, Median Wrist, Ulnar Wrist (and Ulnar Elbow if applicable).');
            lgd.FontSize = 25;
            
            lgd = xlabel('Time (ms)');
            lgd.FontSize = 25;
            lgd = ylabel('Potential (mV)');
            lgd.FontSize = 25;
        end

        % Callback function
        function EnterMedianElbowOnsetPointEditFieldValueChanged(app, event)
            global ome
            ome = app.EnterMedianElbowOnsetPointEditField.Value;
            app.Lamp_4.Color = 'green';
            while app.EnterMedianElbowOnsetPointEditField.Value == '0'
                app.Lamp_4.Color = 'red';
                ome = app.EnterMedianElbowOnsetPointEditField.Value;
            end
        end

        % Value changed function: 
        % EnterMedianWristOnsetPointEditField_2
        function EnterMedianWristOnsetPointEditField_2ValueChanged(app, event)
            global omw
            omw = app.EnterMedianWristOnsetPointEditField_2.Value;
            app.Lamp_5.Color = 'green';
            while app.EnterMedianElbowOnsetPointEditField.Value == '0' 
                app.Lamp_5.Color = 'red';
                omw = app.EnterMedianWristOnsetPointEditField_2.Value;
            end
        end

        % Value changed function: EnterUlnarWristOnsetPointEditField
        function EnterUlnarWristOnsetPointEditFieldValueChanged(app, event)
            global ouw
            ouw = app.EnterUlnarWristOnsetPointEditField.Value;
            app.Lamp_6.Color = 'green';
            while app.EnterUlnarWristOnsetPointEditField.Value == '0'
                app.Lamp_6.Color = 'red';
                ouw = app.EnterMedianElbowOnsetPointEditField.Value;
            end
        end

        % Button pushed function: PlotShiftedWaveformsButton
        function PlotShiftedWaveformsButtonPushed(app, event)
            global me
            global mw
            global uw
            global ue
            global ome
            global omw
            global ouw
            global oue
            global sme
            global smw
            global suw
            global sue
            global a
            global b
            global sizeSME
            global sizeSUE
            hold off
            
            sme = me(ome:end); % Adding "s" = shifted
            [sizeSME,valueME] = size(me);
            smw = mw(omw:(sizeSME - ome + omw));
            
            sue = ue(oue:end);
            [sizeSUE,valueUE] = size(uw);
            suw = uw(ouw:(sizeSUE - oue + ouw));
            
            a = sme - smw;
            b = suw - sue;
            
            % 1. Plot of Median Elbow & Median Wrist
            figure('Name',"Plot of Shifted Median & Ulnar Waveforms, and their respective subtractions.",'Color','#fffeea');
            subplot(2,1,1)
            hold off
            plot(sme,'color','[0.1451 0.5882 0.7451]','LineWidth',3)
            ylim([-3 5]);
            hold on
            plot(smw,'color','[0.9059 0.1647 0.1542]','LineWidth',3)
            plot(a,'color','[0.0157 0.6196 0.4510]','LineWidth',4)
            
            
                % legends & axes
            lgd = legend('Median Elbow','Median Wrist','Subtraction');
            lgd.FontSize = 25;
            
            lgd = title('Plot of Shifted Median Elbow, Median Wrist, and Subtraction');
            lgd.FontSize = 25;
            
            lgd = xlabel('Time (ms)');
            lgd.FontSize = 25;
            lgd = ylabel('Potential (mV)');
            lgd.FontSize = 25;
            
            % 2. Plot of Ulnar Elbow & Ulnar Wrist
            subplot(2,1,2)
            plot(sue,'color','[0.6431 0.5176 0.8157]','LineWidth',3)
            ylim([-3 5]);
            hold on
            plot(suw,'color','[0.8863 0.5294 0.2627]','LineWidth',3)
            plot(b,'color','[0.0157 0.6196 0.4510]','LineWidth',4)
            
                % legends & axes
            lgd = legend('Ulnar Elbow', 'Ulnar Wrist','Subtraction');
            lgd.FontSize = 25;
            
            lgd = title('Plot of Shifted Ulnar Elbow, Ulnar Wrist, and Subtraction');
            lgd.FontSize = 25;
            
            lgd = xlabel('Time (ms)');
            lgd.FontSize = 25;
            lgd = ylabel('Potential (mV)');
            lgd.FontSize = 25;
        end

        % Button pushed function: AnalyzeWaveformsButton
        function AnalyzeWaveformsButtonPushed(app, event)
            global sme
            global smw
            global suw
            global sue
            global uw
            global ue
            global a
            global b
            hold off
            
                % 1. Plot of Median Elbow & Median Wrist.
            %figure('Name',"Plot of Analyzed Waveforms",'Color','#fffeea');
            %subplot(3,1,1)
            %plot(sme,'color',[0.0039 0.5098 0.7059],'LineWidth',2)
            %hold on
            %plot(smw,'color','r','LineWidth',2)
            
            %ylim([-3 8]);
            
                % create visible x-axis line
            %area(0)
            
                % legends & axes for first plot
            %lgd = legend('Median Wrist','Median Elbow');
            %lgd.FontSize = 25;
            
            %lgd = title('Plot of Median Elbow and Median Wrist');
            %lgd.FontSize = 25;
            
            %lgd = xlabel('Time (ms)');
            %lgd.FontSize = 25;
            %lgd = ylabel('Potential (mV)');
            %lgd.FontSize = 25;
            
            
            % To calculate area... in the future: lgd =
            % legend(sprintf('Negative Peak Area of Median Subtraction = %f',trapz(a)))

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % 1. Plot of Subtracted Median Waveform.
            figure('Name',"Plot of Analyzed Waveforms",'Color','#fffeea');
            subplot(2,1,1)
            plot(a,'color','[0.0157 0.6196 0.4510]','LineWidth',4)%%% outline of area
            
            ylim([-3 5]);
            
            hold on
            trapz(a) %%% numeric value of area
            
            plot(NaN,'om') %%% colour of median amplitude
            
            area(a,'FaceColor','[0.3216 0.9804 0.5961]') %%% colour of area of difference
            
            % legends & axes for 2nd plot
            lgd = legend(sprintf('Negative Peak Amplitude of Median Subtraction = %.1f',(abs(max(a)))));
            lgd.FontSize = 25;
            %(-min(p))
            
            lgd = title('Difference Between Median Elbow and Median Wrist');
            lgd.FontSize = 25;
            
            lgd = xlabel('Time (ms)');
            lgd.FontSize = 25;
            lgd = ylabel('Potential (mV)');
            lgd.FontSize = 25;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % 2. Plot of Subtracted Ulnar Waveform.
            subplot(2,1,2)
            plot(b,'color','[0.0157 0.6196 0.4510]','Linewidth',4)
            
            ylim([-3 5]);
            
            hold on
            trapz(b)
            
            plot(NaN,'om')
            
            area(b,'FaceColor','[0.3216 0.9804 0.5961]')
            
            % legends & axes for 2nd plot
            lgd = legend(sprintf('Negative Peak Amplitude of Ulnar Subtraction = %.1f',abs(max(b))));
            lgd.FontSize = 25;
            %(-min(p))
            
            lgd = title('Difference Between Ulnar Elbow and Ulnar Wrist');
            lgd.FontSize = 25;
            
            lgd = xlabel('Time (ms)');
            lgd.FontSize = 25;
            lgd = ylabel('Potential (mV)');
            lgd.FontSize = 25;
            
        
                % 3. Plot of Ulnar Wrist Waveform.
            %tempuw = uw(10:end);
            %tempue = ue(10:end);
            %subplot(3,1,3)
            %plot(tempuw, 'color', '[0.7176 0.2745 1.0000]', 'LineWidth',2)
            %hold on
            %plot(tempue, 'color', '[1.0000 0.4118 0.1608]', 'LineWidth',2)
            
            %ylim([-3 8]);
            
            %hold on
            %plot(NaN,'og') %%% colour of ulnar amplitude
            %area(0)
            
                % legends & axes for 3rd plot
            %lgd = legend(sprintf('Ulnar Wrist Reference Negative Peak Amplitude = %f',abs(max(tempuw))));
            %lgd.FontSize = 25;
            
            %lgd = title('Plot of Ulnar Wrist');
            %lgd.FontSize = 25;
            
            %lgd = xlabel('Time (ms)');
            %lgd.FontSize = 25;
            %lgd = ylabel('Potential (mV)');
            %lgd.FontSize = 25;
        end

        % Value changed function: 
        % EnterMedianElbowOnsetPointEditField
        function EnterMedianElbowOnsetPointEditFieldValueChanged2(app, event)
            global ome
            ome = app.EnterMedianElbowOnsetPointEditField.Value;
            app.Lamp_4.Color = 'green';
            while app.EnterMedianElbowOnsetPointEditField.Value == '0'
                app.Lamp_4.Color = 'red';
                ome = app.EnterMedianElbowOnsetPointEditField.Value;
            end 
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Lamp.Color = 'red';
            app.Lamp_2.Color = 'red';
            app.Lamp_3.Color = 'red';
            app.Lamp_4.Color = 'red';
            app.Lamp_5.Color = 'red';
            app.Lamp_6.Color = 'red';
            app.Lamp_7.Color = 'red';
            app.Lamp_8.Color = 'red';
            
            clc;
            clear;
            clear all;
        end

        % Button pushed function: 
        % SelecttheUlnarElbowaxoscopefileButton
        function SelecttheUlnarElbowaxoscopefileButtonPushed(app, event)
            global ue
            ue = axoload / 1000;
            prompt = {'Were you able to select the appropriate file? Enter [y/n]'};
            dlgtitle = 'Confirmation of file.';
            definput={'y / n'};
            dims = [1 40];
            opts.Interpreter ='tex';
            selected = inputdlg(prompt, dlgtitle, dims, definput, opts); 
            
            if selected == "y"
                app.Lamp_7.Color = 'green';
            else
                app.Lamp_7.Color = 'red';
            end
            
            while selected == "n"
                app.Lamp_7.Color = 'red';
                axoload;
                selected = inputdlg(prompt, dlgtitle, dims, definput, opts); 
                if selected == "y"
                    ue = ans / 1000;
                    app.Lamp_7.Color = 'green';
                    continue 
                end
            end
        end

        % Value changed function: EnterUlnarElbowOnsetPointEditField
        function EnterUlnarElbowOnsetPointEditFieldValueChanged(app, event)
            global oue
            oue = app.EnterUlnarElbowOnsetPointEditField.Value;
            app.Lamp_8.Color = 'green';
            while app.EnterUlnarElbowOnsetPointEditField.Value == '0'
                app.Lamp_8.Color = 'red';
                oue = app.EnterUlnarElbowOnsetPointEditField.Value;
            end 
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {935, 935};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {1208, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.EnterMedianWristOnsetPointEditField_2Label = uilabel(app.LeftPanel);
            app.EnterMedianWristOnsetPointEditField_2Label.Text = 'Enter Median Wrist Onset Point';
            app.EnterMedianWristOnsetPointEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.EnterMedianWristOnsetPointEditField_2.ValueChangedFcn = createCallbackFcn(app, @EnterMedianWristOnsetPointEditField_2ValueChanged, true);
            app.EnterUlnarWristOnsetPointEditFieldLabel = uilabel(app.LeftPanel);
            app.EnterUlnarWristOnsetPointEditFieldLabel.Text = 'Enter Ulnar Wrist Onset Point';
            app.EnterUlnarWristOnsetPointEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EnterUlnarWristOnsetPointEditField.ValueChangedFcn = createCallbackFcn(app, @EnterUlnarWristOnsetPointEditFieldValueChanged, true);
            app.SelecttheMedianElbowaxoscopefileButton = uibutton(app.LeftPanel, 'push');
            app.SelecttheMedianElbowaxoscopefileButton.ButtonPushedFcn = createCallbackFcn(app, @SelecttheMedianElbowaxoscopefileButtonPushed, true);
            app.SelecttheMedianElbowaxoscopefileButton.Icon = 'Select Icon.png';
            app.SelecttheMedianElbowaxoscopefileButton.Text = 'Select the Median Elbow axoscope file!';
            app.SelecttheMedianWristaxoscopefileButton = uibutton(app.LeftPanel, 'push');
            app.SelecttheMedianWristaxoscopefileButton.ButtonPushedFcn = createCallbackFcn(app, @SelecttheMedianWristaxoscopefileButtonPushed, true);
            app.SelecttheMedianWristaxoscopefileButton.Icon = 'Select Icon.png';
            app.SelecttheMedianWristaxoscopefileButton.Text = 'Select the Median Wrist axoscope file!';
            app.SelecttheUlnarWristaxoscopefileButton = uibutton(app.LeftPanel, 'push');
            app.SelecttheUlnarWristaxoscopefileButton.ButtonPushedFcn = createCallbackFcn(app, @SelecttheUlnarWristaxoscopefileButtonPushed, true);
            app.SelecttheUlnarWristaxoscopefileButton.Icon = 'Select Icon.png';
            app.SelecttheUlnarWristaxoscopefileButton.Text = 'Select the Ulnar Wrist axoscope file!';
            app.PlotRawWaveformsButton = uibutton(app.LeftPanel, 'push');
            app.PlotRawWaveformsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRawWaveformsButtonPushed, true);
            app.PlotRawWaveformsButton.Icon = 'Plot Icon.png';
            app.PlotRawWaveformsButton.Text = 'Plot Raw Waveforms.';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = ' ';
            app.Lamp = uilamp(app.LeftPanel);
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = ' ';
            app.Lamp_2 = uilamp(app.LeftPanel);
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = ' ';
            app.Lamp_3 = uilamp(app.LeftPanel);
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = ' ';
            app.Lamp_4 = uilamp(app.LeftPanel);
            app.Label_5 = uilabel(app.LeftPanel);
            app.Label_5.Text = ' ';
            app.Lamp_5 = uilamp(app.LeftPanel);
            app.Label_6 = uilabel(app.LeftPanel);
            app.Label_6.Text = ' ';
            app.Lamp_6 = uilamp(app.LeftPanel);
            app.PlotShiftedWaveformsButton = uibutton(app.LeftPanel, 'push');
            app.PlotShiftedWaveformsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotShiftedWaveformsButtonPushed, true);
            app.PlotShiftedWaveformsButton.Icon = 'Plot Icon.png';
            app.PlotShiftedWaveformsButton.Text = 'Plot Shifted Waveforms.';
            app.AnalyzeWaveformsButton = uibutton(app.LeftPanel, 'push');
            app.AnalyzeWaveformsButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeWaveformsButtonPushed, true);
            app.AnalyzeWaveformsButton.Icon = 'Analyze Icon.png';
            app.AnalyzeWaveformsButton.Text = 'Analyze Waveforms.';
            app.EnterMedianElbowOnsetPointEditFieldLabel = uilabel(app.LeftPanel);
            app.EnterMedianElbowOnsetPointEditFieldLabel.Text = 'Enter Median Elbow Onset Point';
            app.EnterMedianElbowOnsetPointEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EnterMedianElbowOnsetPointEditField.ValueChangedFcn = createCallbackFcn(app, @EnterMedianElbowOnsetPointEditFieldValueChanged2, true);
            app.ProgramtoInterrogateNerveCrossoverLabel = uilabel(app.LeftPanel);
            app.ProgramtoInterrogateNerveCrossoverLabel.Text = 'Program to Interrogate Nerve Crossover';
            app.Label_7 = uilabel(app.LeftPanel);
            app.Label_7.Text = '→';
            app.Label_8 = uilabel(app.LeftPanel);
            app.Label_8.Text = '→';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Icon = 'Reset Icon.jpg';
            app.ResetButton.Text = 'Reset';
            app.SelecttheUlnarElbowaxoscopefileButton = uibutton(app.LeftPanel, 'push');
            app.SelecttheUlnarElbowaxoscopefileButton.ButtonPushedFcn = createCallbackFcn(app, @SelecttheUlnarElbowaxoscopefileButtonPushed, true);
            app.SelecttheUlnarElbowaxoscopefileButton.Icon = 'Select Icon.png';
            app.SelecttheUlnarElbowaxoscopefileButton.Text = 'Select the Ulnar Elbow axoscope file!';
            app.Lamp_7Label = uilabel(app.LeftPanel);
            app.Lamp_7Label.Text = ' ';
            app.Lamp_7 = uilamp(app.LeftPanel);
            app.EnterUlnarElbowOnsetPointEditFieldLabel = uilabel(app.LeftPanel);
            app.EnterUlnarElbowOnsetPointEditFieldLabel.Text = 'Enter Ulnar Elbow Onset Point';
            app.EnterUlnarElbowOnsetPointEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EnterUlnarElbowOnsetPointEditField.ValueChangedFcn = createCallbackFcn(app, @EnterUlnarElbowOnsetPointEditFieldValueChanged, true);
            app.Lamp_8Label = uilabel(app.LeftPanel);
            app.Lamp_8Label.Text = ' ';
            app.Lamp_8 = uilamp(app.LeftPanel);
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'Blue.png';
            app.Image_2 = uiimage(app.LeftPanel);
            app.Image_2.ImageSource = 'Orange.png';
            app.Image_3 = uiimage(app.LeftPanel);
            app.Image_3.ImageSource = 'Purple.png';
            app.Image_4 = uiimage(app.LeftPanel);
            app.Image_4.ImageSource = 'Pink.png';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Plotable

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end