classdef Plotable < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        EnterMedianWristOnsetPointEditField_2Label  matlab.ui.control.Label
        EnterMedianWristOnsetPointEditField_2  matlab.ui.control.NumericEditField
        EnterUlnarWristOnsetPointEditFieldLabel  matlab.ui.control.Label
        EnterUlnarWristOnsetPointEditField  matlab.ui.control.NumericEditField
        SelecttheMedianElbowaxoscopefileButton  matlab.ui.control.Button
        SelecttheMedianWristaxoscopefileButton  matlab.ui.control.Button
        SelecttheUlnarWristaxoscopefileButton  matlab.ui.control.Button
        PlotRawWaveformsButton      matlab.ui.control.Button
        Lamp                        matlab.ui.control.Lamp
        Lamp_2                      matlab.ui.control.Lamp
        Lamp_3                      matlab.ui.control.Lamp
        Lamp_4                      matlab.ui.control.Lamp
        Lamp_5                      matlab.ui.control.Lamp
        Lamp_6                      matlab.ui.control.Lamp
        PlotShiftedWaveformsButton  matlab.ui.control.Button
        AnalyzeWaveformsButton      matlab.ui.control.Button
        EnterMedianElbowOnsetPointEditFieldLabel  matlab.ui.control.Label
        EnterMedianElbowOnsetPointEditField  matlab.ui.control.NumericEditField
        PlotableInterrogateNerveCrossoverLabel  matlab.ui.control.Label
        Label_7                     matlab.ui.control.Label
        ResetButton                 matlab.ui.control.Button
        SelecttheUlnarElbowaxoscopefileButton  matlab.ui.control.Button
        Lamp_7                      matlab.ui.control.Lamp
        EnterUlnarElbowOnsetPointEditFieldLabel  matlab.ui.control.Label
        EnterUlnarElbowOnsetPointEditField  matlab.ui.control.NumericEditField
        Lamp_8                      matlab.ui.control.Lamp
        Image                       matlab.ui.control.Image
        Image_2                     matlab.ui.control.Image
        Image_3                     matlab.ui.control.Image
        Image_4                     matlab.ui.control.Image
        SamplingRatekHzKnob_2Label  matlab.ui.control.Label
        SamplingRatekHzKnob_2       matlab.ui.control.DiscreteKnob
        GainDropDownLabel           matlab.ui.control.Label
        GainDropDown                matlab.ui.control.DropDown
        GainDropDown_2Label         matlab.ui.control.Label
        GainDropDown_2              matlab.ui.control.DropDown
        GainDropDown_3Label         matlab.ui.control.Label
        GainDropDown_3              matlab.ui.control.DropDown
        GainDropDown_4Label         matlab.ui.control.Label
        GainDropDown_4              matlab.ui.control.DropDown
        RightPanel                  matlab.ui.container.Panel
        ContextMenu                 matlab.ui.container.ContextMenu
        Menu                        matlab.ui.container.Menu
        Menu2                       matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: 
        % SelecttheMedianElbowaxoscopefileButton
        function SelecttheMedianElbowaxoscopefileButtonPushed(app, event)
            global zero
            zero = [0];
            
            global me_
            me0 = axoload / 1000; % 1000 converts µV to mV.
            me_ = vertcat(zero, me0);
            
            app.Lamp.Color = 'green';
            %prompt = {'Were you able to select the appropriate file? Enter [y/n]'};
            %dlgtitle = 'Confirmation of file.';
            %definput={'y / n'};
            %dims = [1 40];
            %opts.Interpreter ='tex';
            %selected = inputdlg(prompt, dlgtitle, dims, definput, opts); 
            
            %if selected == "y"
            %    app.Lamp.Color = 'green';
            %else
            %    app.Lamp.Color = 'red';
            %end
            
            %while selected == "n"
            %    app.Lamp.Color = 'red';
            %    axoload;
            %    selected = inputdlg(prompt, dlgtitle, dims, definput, opts); 
            %    if selected == "y"
            %        me0 = ans / 1000; % 1000 coverts µV to mV.
            %        me_ = vertcat(zero, me0);
            %        app.Lamp.Color = 'green';
            %        continue 
            %    end
            %end
            
            global sampling_rate
            sampling_rate = 5; %set '5' as default sampling_rate, in the case the user does not change the sampling rate.
        end

        % Callback function
        function AppropriatefileEnterynEditFieldValueChanging(app, event)
            
        end

        % Button pushed function: 
        % SelecttheMedianWristaxoscopefileButton
        function SelecttheMedianWristaxoscopefileButtonPushed(app, event)
            global zero
            global mw_
            mw0 = axoload / 1000; % 1000 coverts µV to mV.
            mw_ = vertcat(zero, mw0);
            
            app.Lamp_2.Color = 'green';
            %prompt = {'Were you able to select the appropriate file? Enter [y/n]'};
            %dlgtitle = 'Confirmation of file.';
            %definput={'y / n'};
            %dims = [1 40];
            %opts.Interpreter ='tex';
            %selected = inputdlg(prompt, dlgtitle, dims, definput, opts);
            %app.Lamp_2.Color = 'green';
            
            %if selected == "y"
            %    app.Lamp_2.Color = 'green';
            %else
            %    app.Lamp_2.Color = 'red';
            %end
            
            %while selected == "n"
            %    app.Lamp_2.Color = 'red';
            %    axoload;
            %    selected = inputdlg(prompt, dlgtitle, dims, definput, opts);
            %    if selected == "y"
            %        mw0 = ans / 1000; % 1000 coverts µV to mV.
            %        mw_ = vertcat(zero, mw0);
            %        app.Lamp_2.Color = 'green';
            %        continue 
            %    end
            %end
        end

        % Button pushed function: 
        % SelecttheUlnarWristaxoscopefileButton
        function SelecttheUlnarWristaxoscopefileButtonPushed(app, event)
            global zero
            global uw_
            uw0 = axoload / 1000; % 1000 coverts µV to mV.
            uw_ = vertcat(zero, uw0);
            
            app.Lamp_3.Color = 'green';            
            %prompt = {'Were you able to select the appropriate file? Enter [y/n]'};
            %dlgtitle = 'Confirmation of file.';
            %definput={'y / n'};
            %dims = [1 40];
            %opts.Interpreter ='tex';
            %selected = inputdlg(prompt, dlgtitle, dims, definput, opts);
            %app.Lamp_3.Color = 'green';
            
            %if selected == "y"
            %    app.Lamp_3.Color = 'green';
            %else
            %    app.Lamp_3.Color = 'red';
            %end
            
            %while selected == "n"
            %    app.Lamp_3.Color = 'red';
            %    axoload;
            %    selected = inputdlg(prompt, dlgtitle, dims, definput, opts); 
            %    if selected == "y"
            %        uw0 = ans / 1000; % 1000 coverts µV to mV.
            %        uw_ = vertcat(zero, uw0);
            %        app.Lamp_3.Color = 'green';
            %        continue 
            %    end
            %end
        end

        % Button pushed function: PlotRawWaveformsButton
        function PlotRawWaveformsButtonPushed(app, event)
            global me_
            global me
            global met
            global mw_
            global mw
            global mwt
            global ue_
            global ue
            global uet
            global uw_
            global uw
            global uwt
            global medianElbow_gain
            global medianWrist_gain
            global ulnarElbow_gain
            global ulnarWrist_gain
            global sampling_rate
                        
            
            %global me0
            me = medianElbow_gain * me_; % calibrates gain.
            met = (0:numel(me)-1) / sampling_rate;
            
            %global mw0
            mw = medianWrist_gain * mw_; % calibrates gain.
            mwt = (0:numel(mw)-1) / sampling_rate;
            
            %global ue0
            ue = ulnarElbow_gain * ue_; % calibrates gain.
            uet = (0:numel(ue)-1) / sampling_rate;
            
            %global uw0
            uw = ulnarWrist_gain * uw_; % calibrates gain.
            uwt = (0:numel(uw)-1) / sampling_rate;
            
            
            figure('Name',"Plot of Raw Waveforms.",'Color','#fffeea');
            subplot(4,1,1)
            plot(met,me,'color','[0.1451 0.5882 0.7451]','LineWidth',3)
            % legends & axes
            lgd = legend('Median Elbow');
            lgd.FontSize = 25;
            lgd = title('Plot of Median Elbow.');
            lgd.FontSize = 25;
            lgd = xlabel('Time (ms)');
            lgd.FontSize = 25;
            lgd = ylabel('Potential (mV)');
            lgd.FontSize = 25;
            
            subplot(4,1,2)
            plot(mwt,mw,'color','[0.9059 0.1647 0.1542]','LineWidth',3)
            % legends & axes
            lgd = legend('Median Wrist');
            lgd.FontSize = 25;
            lgd = title('Plot of Median Wrist.');
            lgd.FontSize = 25;
            lgd = xlabel('Time (ms)');
            lgd.FontSize = 25;
            lgd = ylabel('Potential (mV)');
            lgd.FontSize = 25;
            
            subplot(4,1,3)
            plot(uet,ue,'color','[0.6431 0.5176 0.8157]','LineWidth',3)
            % legends & axes
            lgd = legend('Ulnar Elbow');
            lgd.FontSize = 25;
            lgd = title('Plot of Ulnar Elbow.');
            lgd.FontSize = 25;
            lgd = xlabel('Time (ms)');
            lgd.FontSize = 25;
            lgd = ylabel('Potential (mV)');
            lgd.FontSize = 25;
            
            subplot(4,1,4)
            plot(uwt,uw,'color','[0.8863 0.5294 0.2627]','LineWidth',3)
            % legends & axes
            lgd = legend('Ulnar Wrist');
            lgd.FontSize = 25;
            lgd = title('Plot of Ulnar Wrist.');
            lgd.FontSize = 25;
            lgd = xlabel('Time (ms)');
            lgd.FontSize = 25;
            lgd = ylabel('Potential (mV)');
            lgd.FontSize = 25;
        end

        % Callback function
        function EnterMedianElbowOnsetPointEditFieldValueChanged(app, event)
            global ome
            ome = app.EnterMedianElbowOnsetPointEditField.Value;
            app.Lamp_4.Color = 'green';
            while app.EnterMedianElbowOnsetPointEditField.Value == '0'
                app.Lamp_4.Color = 'red';
                ome = app.EnterMedianElbowOnsetPointEditField.Value;
            end
        end

        % Value changed function: 
        % EnterMedianWristOnsetPointEditField_2
        function EnterMedianWristOnsetPointEditField_2ValueChanged(app, event)
            global omw
            omw = app.EnterMedianWristOnsetPointEditField_2.Value;
            app.Lamp_5.Color = 'green';
            while app.EnterMedianElbowOnsetPointEditField.Value == '0' 
                app.Lamp_5.Color = 'red';
                omw = app.EnterMedianWristOnsetPointEditField_2.Value;
            end
        end

        % Value changed function: EnterUlnarWristOnsetPointEditField
        function EnterUlnarWristOnsetPointEditFieldValueChanged(app, event)
            global ouw
            ouw = app.EnterUlnarWristOnsetPointEditField.Value;
            app.Lamp_6.Color = 'green';
            while app.EnterUlnarWristOnsetPointEditField.Value == '0'
                app.Lamp_6.Color = 'red';
                ouw = app.EnterMedianElbowOnsetPointEditField.Value;
            end
        end

        % Button pushed function: PlotShiftedWaveformsButton
        function PlotShiftedWaveformsButtonPushed(app, event)
            global me
            global met
            global mw
            global mwt
            global uw
            global uwt
            global ue
            global uet
            global ome
            global omw
            global ouw
            global oue
            global sme
            global smw
            global suw
            global sue
            global a
            global b
            global smet
            global smwt
            global suet
            global suwt
            global sampling_rate
            global ssmet
            global ssuet
            hold off
            
            smet = met(sampling_rate*ome + 1:end);
            sme  = me(sampling_rate*ome + 1:end); % works off last line.
            
            smwt = mwt(sampling_rate*omw + 1:sampling_rate*omw + numel(smet));
            smw  = mw(sampling_rate*omw + 1:sampling_rate*omw + numel(smet));
            
            ssmet = smet - (smet(1) - smwt(1)); 
                        
            suet = uet(sampling_rate*oue + 1:end);
            sue  = ue(sampling_rate*oue + 1:end);
            
            suwt = uwt(sampling_rate*ouw + 1:sampling_rate*ouw + numel(suet));
            suw  = uw(sampling_rate*ouw + 1:sampling_rate*ouw + numel(suet));
            
            ssuet = suet - (suet(1) - suwt(1));

            a = sme - smw;
            b = suw - sue;
            
            % 1. Plot of Median Elbow & Median Wrist
            figure('Name',"Plot of Shifted Median & Ulnar Waveforms, and their respective subtractions.",'Color','#fffeea');
            subplot(2,1,1)
            hold off
            plot(ssmet,sme,'color','[0.1451 0.5882 0.7451]','LineWidth',3)
            %xlim([ssmet(1,1) ssmet(1,1)+20]);
            xlim([ssmet(1,1) ssmet(1,1)+20]);
            ylim([-3 6]);
            hold on
            plot(smwt,smw,'color','[0.9059 0.1647 0.1542]','LineWidth',3)
            plot(ssmet,a,'color','[0.0157 0.6196 0.4510]','LineWidth',4)


                % legends & axes
            lgd = legend('Median Elbow','Median Wrist','Subtraction');
            lgd.FontSize = 25;
            
            lgd = title('Plot of Shifted Median Elbow, Median Wrist, and Subtraction');
            lgd.FontSize = 25;
            
            lgd = xlabel('Time (ms)');
            lgd.FontSize = 25;
            lgd = ylabel('Potential (mV)');
            lgd.FontSize = 25;
            
            % 2. Plot of Ulnar Elbow & Ulnar Wrist
            subplot(2,1,2)
            plot(ssuet,sue,'color','[0.6431 0.5176 0.8157]','LineWidth',3)
            xlim([ssuet(1,1) ssuet(1,1)+20]);
            ylim([-3 6]);
            hold on
            plot(suwt,suw,'color','[0.8863 0.5294 0.2627]','LineWidth',3)
            plot(ssuet,b,'color','[0.0157 0.6196 0.4510]','LineWidth',4)
            
                % legends & axes
            lgd = legend('Ulnar Elbow', 'Ulnar Wrist','Subtraction');
            lgd.FontSize = 25;
            
            lgd = title('Plot of Shifted Ulnar Elbow, Ulnar Wrist, and Subtraction');
            lgd.FontSize = 25;
            
            lgd = xlabel('Time (ms)');
            lgd.FontSize = 25;
            lgd = ylabel('Potential (mV)');
            lgd.FontSize = 25;
        end

        % Button pushed function: AnalyzeWaveformsButton
        function AnalyzeWaveformsButtonPushed(app, event)
            global ssmet
            global ssuet
            global a
            global b
            hold off
            
                % 1. Plot of Median Elbow & Median Wrist.
            %figure('Name',"Plot of Analyzed Waveforms",'Color','#fffeea');
            %subplot(3,1,1)
            %plot(sme,'color',[0.0039 0.5098 0.7059],'LineWidth',2)
            %hold on
            %plot(smw,'color','r','LineWidth',2)
            
            %ylim([-3 8]);
            
                % create visible x-axis line
            %area(0)
            
                % legends & axes for first plot
            %lgd = legend('Median Wrist','Median Elbow');
            %lgd.FontSize = 25;
            
            %lgd = title('Plot of Median Elbow and Median Wrist');
            %lgd.FontSize = 25;
            
            %lgd = xlabel('Time (ms)');
            %lgd.FontSize = 25;
            %lgd = ylabel('Potential (mV)');
            %lgd.FontSize = 25;
            
            
            % To calculate area... in the future: lgd =
            % legend(sprintf('Negative Peak Area of Median Subtraction = %f',trapz(a)))

            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % 1. Plot of Subtracted Median Waveform.
            figure('Name',"Plot of Analyzed Waveforms",'Color','#fffeea');
            subplot(2,1,1)
            plot(ssmet,a,'color','[0.0157 0.6196 0.4510]','LineWidth',4)%%% outline of area
            
            %xlim([ssmet(1,1) ssmet(1,1)+20]);
            xlim([ssmet(1,1) ssmet(1,1)+20]);
            ylim([-3 6]);
            
            hold on
            trapz(ssmet,a) %%% numeric value of area
            
            plot(NaN,'om') %%% colour of median amplitude
            
            area(ssmet,a,'FaceColor','[0.3216 0.9804 0.5961]') %%% colour of area of difference
            
            % legends & axes for 2nd plot
            lgd = legend(sprintf('Negative Peak Amplitude of Median Subtraction (mV) = %.2f', (abs(max(a)))),sprintf('Negative Peak-to-Peak Amplitude of Median Subtraction (mV) = %.2f', (abs(min(a))+abs(max(a)))));               
            %lgd = legend(sprintf('Negative Peak Amplitude of Median Subtraction (mV) = %.1f', (abs(max(a)))));
            %lgd = legend(sprintf('Negative Peak Amplitude of Median Subtraction (mV) = %.1f', (abs(min(a))+abs(max(a)))));
            %lgd = legend(sprintf('Negative Peak-to-Peak Amplitude of Median Subraction (mV) = %.if', (abs(max(a))+abs(min(a)))));
            lgd.FontSize = 25;
            %(-min(p))
            
            lgd = title('Difference Between Median Elbow and Median Wrist');
            lgd.FontSize = 25;
            
            lgd = xlabel('Time (ms)');
            lgd.FontSize = 25;
            lgd = ylabel('Potential (mV)');
            lgd.FontSize = 25;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % 2. Plot of Subtracted Ulnar Waveform.
            subplot(2,1,2)
            plot(ssuet,b,'color','[0.0157 0.6196 0.4510]','Linewidth',4)
           
            xlim([ssuet(1,1) ssuet(1,1)+20]);

            ylim([-3 6]);
            
            hold on
            trapz(ssuet,b)
            
            plot(NaN,'om')
            
            area(ssuet,b,'FaceColor','[0.3216 0.9804 0.5961]')
            
            % legends & axes for 2nd plot
            lgd = legend(sprintf('Negative Peak Amplitude of Ulnar Subtraction (mV) = %.2f', (abs(max(b)))),sprintf('Negative Peak-to-Peak Amplitude of Ulnar Subtraction (mV) = %.2f', (abs(min(b))+abs(max(b)))));               
            %lgd = legend(sprintf('Negative Peak Amplitude of Ulnar Subtraction (mV) = %.1f',abs(max(b))));
            lgd.FontSize = 25;
            %(-min(p))
            
            lgd = title('Difference Between Ulnar Elbow and Ulnar Wrist');
            lgd.FontSize = 25;
            
            lgd = xlabel('Time (ms)');
            lgd.FontSize = 25;
            lgd = ylabel('Potential (mV)');
            lgd.FontSize = 25;
            
        
                % 3. Plot of Ulnar Wrist Waveform.
            %tempuw = uw(10:end);
            %tempue = ue(10:end);
            %subplot(3,1,3)
            %plot(tempuw, 'color', '[0.7176 0.2745 1.0000]', 'LineWidth',2)
            %hold on
            %plot(tempue, 'color', '[1.0000 0.4118 0.1608]', 'LineWidth',2)
            
            %ylim([-3 8]);
            
            %hold on
            %plot(NaN,'og') %%% colour of ulnar amplitude
            %area(0)
            
                % legends & axes for 3rd plot
            %lgd = legend(sprintf('Ulnar Wrist Reference Negative Peak Amplitude = %f',abs(max(tempuw))));
            %lgd.FontSize = 25;
            
            %lgd = title('Plot of Ulnar Wrist');
            %lgd.FontSize = 25;
            
            %lgd = xlabel('Time (ms)');
            %lgd.FontSize = 25;
            %lgd = ylabel('Potential (mV)');
            %lgd.FontSize = 25;
        end

        % Value changed function: 
        % EnterMedianElbowOnsetPointEditField
        function EnterMedianElbowOnsetPointEditFieldValueChanged2(app, event)
            global ome
            ome = app.EnterMedianElbowOnsetPointEditField.Value;
            app.Lamp_4.Color = 'green';
            while app.EnterMedianElbowOnsetPointEditField.Value == '0'
                app.Lamp_4.Color = 'red';
                ome = app.EnterMedianElbowOnsetPointEditField.Value;
            end 
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Lamp.Color = 'red';
            app.Lamp_2.Color = 'red';
            app.Lamp_3.Color = 'red';
            app.Lamp_4.Color = 'red';
            app.Lamp_5.Color = 'red';
            app.Lamp_6.Color = 'red';
            app.Lamp_7.Color = 'red';
            app.Lamp_8.Color = 'red';

            clc
            clear functions;
            clear
            clear all;
        end

        % Button pushed function: 
        % SelecttheUlnarElbowaxoscopefileButton
        function SelecttheUlnarElbowaxoscopefileButtonPushed(app, event)
            global zero
            global ue_
            ue0 = axoload / 1000; % 1000 coverts µV to mV.
            ue_ = vertcat(zero, ue0);
            
            app.Lamp_7.Color = 'green';
            %prompt = {'Were you able to select the appropriate file? Enter [y/n]'};
            %dlgtitle = 'Confirmation of file.';
            %definput={'y / n'};
            %dims = [1 40];
            %opts.Interpreter ='tex';
            %selected = inputdlg(prompt, dlgtitle, dims, definput, opts); 
            
            %if selected == "y"
            %    app.Lamp_7.Color = 'green';
            %else
            %    app.Lamp_7.Color = 'red';
            %end
            
            %while selected == "n"
            %    app.Lamp_7.Color = 'red';
            %    axoload;
            %    selected = inputdlg(prompt, dlgtitle, dims, definput, opts); 
            %    if selected == "y"
            %        ue0 = ans / 1000; % 1000 coverts µV to mV.
            %        ue_ = vertcat(zero, ue0);
            %        app.Lamp_7.Color = 'green';
            %        continue 
            %    end
            %end
        end

        % Value changed function: EnterUlnarElbowOnsetPointEditField
        function EnterUlnarElbowOnsetPointEditFieldValueChanged(app, event)
            global oue
            oue = app.EnterUlnarElbowOnsetPointEditField.Value;
            app.Lamp_8.Color = 'green';
            while app.EnterUlnarElbowOnsetPointEditField.Value == '0'
                app.Lamp_8.Color = 'red';
                oue = app.EnterUlnarElbowOnsetPointEditField.Value;
            end 
        end

        % Value changed function: SamplingRatekHzKnob_2
        function SamplingRatekHzKnob_2ValueChanged(app, event)
            global sampling_rate
            value = app.SamplingRatekHzKnob_2.Value;
            
            if strcmp(value,'1')
                sampling_rate = [1];
            elseif strcmp(value,'2')
                sampling_rate = [2];
            elseif strcmp(value,'3')
                sampling_rate = [3];
            elseif strcmp(value,'4')
                sampling_rate = [4];
            elseif strcmp(value,'5')
                sampling_rate = [5];
            elseif strcmp(value,'6')
                sampling_rate = [6];
            elseif strcmp(value,'7')
                sampling_rate = [7];
            elseif strcmp(value,'8')
                sampling_rate = [8];
            elseif strcmp(value,'9')
                sampling_rate = [9];
            elseif strcmp(value,'10')
                sampling_rate = [10];
            end
        end

        % Value changed function: GainDropDown
        function GainDropDownValueChanged(app, event)
            global medianElbow_gain
            value = app.GainDropDown.Value;
            
            medianElbow_gain = [0.5];
            
            if strcmp(value,'0.2')
                medianElbow_gain = [0.2];
            elseif strcmp(value,'0.5')
                medianElbow_gain = [0.5];  
            elseif strcmp(value,'1')
                medianElbow_gain = [1];                
            elseif strcmp(value,'2')
                medianElbow_gain = [2];
            elseif strcmp(value,'5')
                medianElbow_gain = [5];
            elseif strcmp(value,'10')
                medianElbow_gain = [10];
            elseif strcmp(value,'20')
                medianElbow_gain = [20];
            end
        end

        % Drop down opening function: GainDropDown_2
        function GainDropDown_2Opening(app, event)
            
        end

        % Value changed function: GainDropDown_2
        function GainDropDown_2ValueChanged(app, event)
            global medianWrist_gain
            value = app.GainDropDown_2.Value;
            
            medianWrist_gain = [5];

            if strcmp(value,'0.2')
                medianWrist_gain = [0.2];
            elseif strcmp(value,'0.5')
                medianWrist_gain = [0.5];
            elseif strcmp(value,'1')
                medianWrist_gain = [1];
            elseif strcmp(value,'2')
                medianWrist_gain = [2];
            elseif strcmp(value,'5')
                medianWrist_gain = [5];
            elseif strcmp(value,'10')
                medianWrist_gain = [10];
            elseif strcmp(value,'20')
                medianWrist_gain = [20];
            end
        end

        % Value changed function: GainDropDown_3
        function GainDropDown_3ValueChanged(app, event)
            global ulnarElbow_gain
            value = app.GainDropDown_3.Value;
            
            ulnarElbow_gain = [5];
            
            if strcmp(value,'0.2')
                ulnarElbow_gain = [0.2];
            elseif strcmp(value,'0.5')
                ulnarElbow_gain = [0.5];
            elseif strcmp(value,'1')
                ulnarElbow_gain = [1];
            elseif strcmp(value,'2')
                ulnarElbow_gain = [2];
            elseif strcmp(value,'5')
                ulnarElbow_gain = [5];
            elseif strcmp(value,'10')
                ulnarElbow_gain = [10];
            elseif strcmp(value,'20')
                ulnarElbow_gain = [20];
            end
        end

        % Value changed function: GainDropDown_4
        function GainDropDown_4ValueChanged(app, event)
            global ulnarWrist_gain
            value = app.GainDropDown_4.Value;
            
            ulnarWrist_gain = [5];
            
            if strcmp(value,'0.2')
                ulnarWrist_gain = [0.2];
            elseif strcmp(value, '0.5')
                ulnarWrist_gain = [0.5];
            elseif strcmp(value,'1')
                ulnarWrist_gain = [1];               
            elseif strcmp(value,'2')
                ulnarWrist_gain = [2];
            elseif strcmp(value,'5')
                ulnarWrist_gain = [5];
            elseif strcmp(value,'10')
                ulnarWrist_gain = [10];
            elseif strcmp(value,'20')
                ulnarWrist_gain = [20];
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {935, 935};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {1896, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.EnterMedianWristOnsetPointEditField_2Label = uilabel(app.LeftPanel);
            app.EnterMedianWristOnsetPointEditField_2Label.Text = 'Enter Median Wrist Onset Point';
            app.EnterMedianWristOnsetPointEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.EnterMedianWristOnsetPointEditField_2.ValueChangedFcn = createCallbackFcn(app, @EnterMedianWristOnsetPointEditField_2ValueChanged, true);
            app.EnterUlnarWristOnsetPointEditFieldLabel = uilabel(app.LeftPanel);
            app.EnterUlnarWristOnsetPointEditFieldLabel.Text = 'Enter Ulnar Wrist Onset Point';
            app.EnterUlnarWristOnsetPointEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EnterUlnarWristOnsetPointEditField.ValueChangedFcn = createCallbackFcn(app, @EnterUlnarWristOnsetPointEditFieldValueChanged, true);
            app.SelecttheMedianElbowaxoscopefileButton = uibutton(app.LeftPanel, 'push');
            app.SelecttheMedianElbowaxoscopefileButton.ButtonPushedFcn = createCallbackFcn(app, @SelecttheMedianElbowaxoscopefileButtonPushed, true);
            app.SelecttheMedianElbowaxoscopefileButton.Icon = 'Select Icon.png';
            app.SelecttheMedianElbowaxoscopefileButton.Text = 'Select the Median Elbow axoscope file';
            app.SelecttheMedianWristaxoscopefileButton = uibutton(app.LeftPanel, 'push');
            app.SelecttheMedianWristaxoscopefileButton.ButtonPushedFcn = createCallbackFcn(app, @SelecttheMedianWristaxoscopefileButtonPushed, true);
            app.SelecttheMedianWristaxoscopefileButton.Icon = 'Select Icon.png';
            app.SelecttheMedianWristaxoscopefileButton.Text = 'Select the Median Wrist axoscope file';
            app.SelecttheUlnarWristaxoscopefileButton = uibutton(app.LeftPanel, 'push');
            app.SelecttheUlnarWristaxoscopefileButton.ButtonPushedFcn = createCallbackFcn(app, @SelecttheUlnarWristaxoscopefileButtonPushed, true);
            app.SelecttheUlnarWristaxoscopefileButton.Icon = 'Select Icon.png';
            app.SelecttheUlnarWristaxoscopefileButton.Text = 'Select the Ulnar Wrist axoscope file';
            app.PlotRawWaveformsButton = uibutton(app.LeftPanel, 'push');
            app.PlotRawWaveformsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRawWaveformsButtonPushed, true);
            app.PlotRawWaveformsButton.Icon = 'Plot Icon.png';
            app.PlotRawWaveformsButton.Text = 'Plot Raw Waveforms';
            app.Lamp = uilamp(app.LeftPanel);
            app.Lamp_2 = uilamp(app.LeftPanel);
            app.Lamp_3 = uilamp(app.LeftPanel);
            app.Lamp_4 = uilamp(app.LeftPanel);
            app.Lamp_5 = uilamp(app.LeftPanel);
            app.Lamp_6 = uilamp(app.LeftPanel);
            app.PlotShiftedWaveformsButton = uibutton(app.LeftPanel, 'push');
            app.PlotShiftedWaveformsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotShiftedWaveformsButtonPushed, true);
            app.PlotShiftedWaveformsButton.Icon = 'Plot Icon.png';
            app.PlotShiftedWaveformsButton.Text = 'Plot Shifted Waveforms';
            app.AnalyzeWaveformsButton = uibutton(app.LeftPanel, 'push');
            app.AnalyzeWaveformsButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeWaveformsButtonPushed, true);
            app.AnalyzeWaveformsButton.Icon = 'Analyze Icon.png';
            app.AnalyzeWaveformsButton.Text = 'Analyze Waveforms';
            app.EnterMedianElbowOnsetPointEditFieldLabel = uilabel(app.LeftPanel);
            app.EnterMedianElbowOnsetPointEditFieldLabel.Text = 'Enter Median Elbow Onset Point';
            app.EnterMedianElbowOnsetPointEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EnterMedianElbowOnsetPointEditField.ValueChangedFcn = createCallbackFcn(app, @EnterMedianElbowOnsetPointEditFieldValueChanged2, true);
            app.PlotableInterrogateNerveCrossoverLabel = uilabel(app.LeftPanel);
            app.PlotableInterrogateNerveCrossoverLabel.Text = 'Plotable — Interrogate Nerve Crossover';
            app.Label_7 = uilabel(app.LeftPanel);
            app.Label_7.Text = '→';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Icon = 'Reset Icon.jpg';
            app.ResetButton.Text = 'Reset';
            app.SelecttheUlnarElbowaxoscopefileButton = uibutton(app.LeftPanel, 'push');
            app.SelecttheUlnarElbowaxoscopefileButton.ButtonPushedFcn = createCallbackFcn(app, @SelecttheUlnarElbowaxoscopefileButtonPushed, true);
            app.SelecttheUlnarElbowaxoscopefileButton.Icon = 'Select Icon.png';
            app.SelecttheUlnarElbowaxoscopefileButton.Text = 'Select the Ulnar Elbow axoscope file';
            app.Lamp_7 = uilamp(app.LeftPanel);
            app.EnterUlnarElbowOnsetPointEditFieldLabel = uilabel(app.LeftPanel);
            app.EnterUlnarElbowOnsetPointEditFieldLabel.Text = 'Enter Ulnar Elbow Onset Point';
            app.EnterUlnarElbowOnsetPointEditField = uieditfield(app.LeftPanel, 'numeric');
            app.EnterUlnarElbowOnsetPointEditField.ValueChangedFcn = createCallbackFcn(app, @EnterUlnarElbowOnsetPointEditFieldValueChanged, true);
            app.Lamp_8 = uilamp(app.LeftPanel);
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'Blue.png';
            app.Image_2 = uiimage(app.LeftPanel);
            app.Image_2.ImageSource = 'Orange.png';
            app.Image_3 = uiimage(app.LeftPanel);
            app.Image_3.ImageSource = 'Purple.png';
            app.Image_4 = uiimage(app.LeftPanel);
            app.Image_4.ImageSource = 'Pink.png';
            app.SamplingRatekHzKnob_2Label = uilabel(app.LeftPanel);
            app.SamplingRatekHzKnob_2Label.Text = 'Sampling Rate (kHz)';
            app.SamplingRatekHzKnob_2 = uiknob(app.LeftPanel, 'discrete');
            app.SamplingRatekHzKnob_2.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.SamplingRatekHzKnob_2.ValueChangedFcn = createCallbackFcn(app, @SamplingRatekHzKnob_2ValueChanged, true);
            app.SamplingRatekHzKnob_2.Value = '5';
            app.GainDropDownLabel = uilabel(app.LeftPanel);
            app.GainDropDownLabel.Text = 'Gain';
            app.GainDropDown = uidropdown(app.LeftPanel);
            app.GainDropDown.Items = {'0.2 mV/d', '0.5 mV/d', '1 mV/d', '2 mV/d', '5 mV/d', '10 mV/d', '20 mV/d'};
            app.GainDropDown.ItemsData = {'0.2', '0.5', '1', '2', '5', '10', '20'};
            app.GainDropDown.ValueChangedFcn = createCallbackFcn(app, @GainDropDownValueChanged, true);
            app.GainDropDown.Value = '5';
            app.GainDropDown_2Label = uilabel(app.LeftPanel);
            app.GainDropDown_2Label.Text = 'Gain';
            app.GainDropDown_2 = uidropdown(app.LeftPanel);
            app.GainDropDown_2.Items = {'0.2 mV/d', '0.5 mV/d', '1 mV/d', '2 mV/d', '5 mV/d', '10 mV/d', '20 mV/d'};
            app.GainDropDown_2.ItemsData = {'0.2', '0.5', '1', '2', '5', '10', '20'};
            app.GainDropDown_2.DropDownOpeningFcn = createCallbackFcn(app, @GainDropDown_2Opening, true);
            app.GainDropDown_2.ValueChangedFcn = createCallbackFcn(app, @GainDropDown_2ValueChanged, true);
            app.GainDropDown_2.Value = '5';
            app.GainDropDown_3Label = uilabel(app.LeftPanel);
            app.GainDropDown_3Label.Text = 'Gain';
            app.GainDropDown_3 = uidropdown(app.LeftPanel);
            app.GainDropDown_3.Items = {'0.2 mV/d', '0.5 mV/d', '1 mV/d', '2 mV/d', '5 mV/d', '10 mV/d', '20 mV/d'};
            app.GainDropDown_3.ItemsData = {'0.2', '0.5', '1', '2', '5', '10', '20'};
            app.GainDropDown_3.ValueChangedFcn = createCallbackFcn(app, @GainDropDown_3ValueChanged, true);
            app.GainDropDown_3.Value = '5';
            app.GainDropDown_4Label = uilabel(app.LeftPanel);
            app.GainDropDown_4Label.Text = 'Gain';
            app.GainDropDown_4 = uidropdown(app.LeftPanel);
            app.GainDropDown_4.Items = {'0.2 mV/d', '0.5 mV/d', '1 mV/d', '2 mV/d', '5 mV/d', '10 mV/d', '20 mV/d'};
            app.GainDropDown_4.ItemsData = {'0.2', '0.5', '1', '2', '5', '10', '20'};
            app.GainDropDown_4.ValueChangedFcn = createCallbackFcn(app, @GainDropDown_4ValueChanged, true);
            app.GainDropDown_4.Value = '5';
            app.RightPanel = uipanel(app.GridLayout);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Plotable

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end